classdef MainAcquisition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Panel1                      matlab.ui.container.Panel
        HomerDropDownLabel          matlab.ui.control.Label
        HomerDropDown               matlab.ui.control.DropDown
        ThoraxDropDownLabel         matlab.ui.control.Label
        ThoraxDropDown              matlab.ui.control.DropDown
        HomerSwitch                 matlab.ui.control.Switch
        ThoraxSwitch                matlab.ui.control.Switch
        Panel2                      matlab.ui.container.Panel
        AutoStopSwitch              matlab.ui.control.Switch
        SubjectSwitch               matlab.ui.control.Switch
        GoldStandardSwitch          matlab.ui.control.Switch
        AutoStopEditFieldLabel      matlab.ui.control.Label
        AutoStopEditField           matlab.ui.control.EditField
        SubjectEditFieldLabel       matlab.ui.control.Label
        SubjectEditField            matlab.ui.control.EditField
        GoldStandardEditFieldLabel  matlab.ui.control.Label
        GoldStandardEditField       matlab.ui.control.EditField
        OtherInfoEditFieldLabel     matlab.ui.control.Label
        OtherInfoEditField          matlab.ui.control.EditField
        OtherInfoSwitch             matlab.ui.control.Switch
        ScanButton                  matlab.ui.control.Button
        StartButton                 matlab.ui.control.Button
        StopButton                  matlab.ui.control.Button
        SaveButton                  matlab.ui.control.Button
        HomerUIAxes                 matlab.ui.control.UIAxes
        ThoraxUIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        SensorsFound
        ExelObj
    end
    
    methods (Static, Access = private)
        
        function SamplingFcn(obj)
            % computing Elapsed Time
            ElapsedTime = (height(obj.ExelData) - obj.ExelFigure.LastFrame) / ...
                obj.SamplingFrequency;
            
            % If ElapsedTime > 1/3 s, so plotting
            if ElapsedTime > 0.3333
                % computing indexes
                ind = (obj.ExelFigure.LastFrame+1 : height(obj.ExelData))';
                
                % computing time
                time = ind / obj.SamplingFrequency;
                
                % setting new XLim if necessary
                if any(time > obj.ExelFigure.Axes.XLim(1,2))
                    obj.ExelFigure.Axes.XLim = obj.ExelFigure.Axes.XLim + 10;
                end
                
                % computing Angle
                angle = computeAngle( ...
                    obj.ExelData.AccX(ind,1), ...
                    obj.ExelData.AccY(ind,1), ...
                    obj.ExelData.AccZ(ind,1)  ...
                    );
                
                addpoints(obj.ExelFigure.Line,time,angle);
                
                % showing now new values
                drawnow
                
                % updating LastFrame
                obj.ExelFigure.LastFrame = height(obj.ExelData);
            end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Position = centerfig('screen',app.UIFigure);
            ScanButtonPushed(app)
        end

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
            % disabling all (except for labels)
            appProp = properties(app);
            for i = 1:numel(appProp)
                cProp = appProp{i,1};
                if not(isa(app.(cProp),'matlab.ui.control.Label'))
                    try %#okAGROW
                        app.(cProp).Enable = 'off';
                    end
                end
            end
            
            % resetting ExelNames and Segments
            app.HomerDropDown.Items = {'Select...'};
            app.ThoraxDropDown.Items = {'Select...'};
            app.HomerSwitch.Value = 'Off';
            app.ThoraxSwitch.Value = 'Off';
            
            % putting shadow on components
            uishadow(app,{'Scanning for';'Bluetooth devices...'})
            
            % getting bluetooth info
            btInfo = instrhwinfo('bluetooth');
            
            % getting bluetooth devices friendly names
            btNames = btInfo.RemoteNames;
            if isempty(btNames), btNames = {}; end
            
            % getting friendly names length
            btLengs = cellfun(@length,btNames);
            
            % removing too short or too long names
            btNames(btLengs<5 & btLengs>10) = [];
            
            % finding friendly names who start with EXLs3
            btInits = cellfun(@(x)x(1:5),btNames,'UniformOutput',false);
            exelInd = cellfun(@isequal,btInits,repmat({'EXLs3'},size(btInits)));
            
            % getting exelnames
            exelNames = sort(btNames(exelInd));
            
            % setting the SensorsFound property
            app.SensorsFound = numel(exelNames);
            
            % enabling components if necessary
            if app.SensorsFound > 0
                % adding the select option
                DropDownItems = ['Select...';exelNames];
                
                % Homer
                app.HomerSwitch.Enable = 'on';
                app.HomerDropDown.Items = DropDownItems;
                app.HomerDropDown.Value = 'Select...';
                
                % Thorax
                app.ThoraxSwitch.Enable = 'on';
                app.ThoraxDropDown.Items = DropDownItems;
                app.ThoraxDropDown.Value = 'Select...';
                
                % enabling options switches
                app.AutoStopSwitch.Enable = 'on';
                app.SubjectSwitch.Enable = 'on';
                app.GoldStandardSwitch.Enable = 'on';
                app.OtherInfoSwitch.Enable = 'on';
                
                % showing EXLs3 devices found
                uishadow(app,[exelNames;'had been found!'])
                
                % removing shadow from components after 2 sec
                pause(2)
                uishadow(app)
            else
                % removing shadow from components
                uishadow(app)
                
                % alerting user
                uialert(app.UIFigure, ...
                    ['No EXLs3 sensor found. ', ...
                    'Be sure that the devices ', ...
                    'you want to use are turned on, ', ...
                    'then retry the scan routine.'], ...
                    'No Sensor Found', ...
                    'Icon','warning')
            end
            
            % in any case, enabling scan button
            app.ScanButton.Enable = 'on';
        end

        % Value changed function: HomerSwitch
        function HomerSwitchValueChanged(app, event)
            switch app.HomerSwitch.Value
                case 'On'
                    if app.SensorsFound == 1
                        app.ThoraxSwitch.Value = 'Off';
                        app.ThoraxSwitch.Enable = 'on';
                        app.ThoraxDropDown.Value = 'Select...';
                        app.ThoraxDropDown.Enable = 'off';
                    end
                    app.HomerDropDown.Enable = 'on';
                case 'Off'
                    if ~strcmp(app.HomerDropDown.Value,'Select...')
                        app.ThoraxDropDown.Items = ['Select...'; ...
                            sort([app.ThoraxDropDown.Items(2:end); ...
                            {app.HomerDropDown.Value}])];
                    end
                    app.ThoraxSwitch.Enable = 'on';
                    app.HomerDropDown.Value = 'Select...';
                    app.HomerDropDown.Enable = 'off';
            end
            
            % checking dropdown values to disenable StartButton
            hValue = string(app.HomerDropDown.Value);
            tValue = string(app.ThoraxDropDown.Value);
            if all([hValue,tValue]=='Select...')
                app.StartButton.Enable = 'off';
            end
        end

        % Value changed function: ThoraxSwitch
        function ThoraxSwitchValueChanged(app, event)
            switch app.ThoraxSwitch.Value
                case 'On'
                    if app.SensorsFound == 1
                        app.HomerSwitch.Value = 'Off';
                        app.HomerSwitch.Enable = 'on';
                        app.HomerDropDown.Value = 'Select...';
                        app.HomerDropDown.Enable = 'off';
                    end
                    app.ThoraxDropDown.Enable = 'on';
                case 'Off'
                    if ~strcmp(app.ThoraxDropDown.Value,'Select...')
                        app.HomerDropDown.Items = ['Select...'; ...
                            unique([app.HomerDropDown.Items(2:end); ...
                            {app.ThoraxDropDown.Value}])];
                    end
                    app.HomerSwitch.Enable = 'on';
                    app.ThoraxDropDown.Value = 'Select...';
                    app.ThoraxDropDown.Enable = 'off';
            end
            
            % checking dropdown values to disenable StartButton
            hValue = string(app.HomerDropDown.Value);
            tValue = string(app.ThoraxDropDown.Value);
            if all([hValue,tValue]=='Select...')
                app.StartButton.Enable = 'off';
            end
        end

        % Value changed function: HomerDropDown
        function HomerDropDownValueChanged(app, event)
            cValue = string(event.Value);
            pValue = string(event.PreviousValue);
            if app.SensorsFound>1
                if pValue == 'Select...' && cValue == 'Select...'
                    % nothing happens
                elseif pValue == 'Select...' && cValue ~= 'Select...'
                    app.ThoraxDropDown.Items = setdiff( ...
                        app.ThoraxDropDown.Items, ...
                        char(cValue),'stable');
                elseif pValue ~= 'Select...' && cValue == 'Select...'
                    app.ThoraxDropDown.Items = ['Select...'; ...
                        sort([app.ThoraxDropDown.Items(2:end); ...
                        {char(pValue)}])];
                elseif pValue ~= 'Select...' && cValue ~= 'Select...'
                    app.ThoraxDropDown.Items = setdiff( ...
                        app.ThoraxDropDown.Items, ...
                        char(cValue),'stable');
                    app.ThoraxDropDown.Items = ['Select...'; ...
                        sort([app.ThoraxDropDown.Items(2:end); ...
                        {char(pValue)}])];
                end
            end
            
            % checking dropdown values to enable StartButton
            hValue = cValue;
            tValue = string(app.ThoraxDropDown.Value);
            if all([hValue,tValue]=='Select...')
                app.StartButton.Enable = 'off';
            else
                app.StartButton.Enable = 'on';
            end
        end

        % Value changed function: ThoraxDropDown
        function ThoraxDropDownValueChanged(app, event)
            cValue = string(event.Value);
            pValue = string(event.PreviousValue);
            if app.SensorsFound>1
                if pValue == 'Select...' && cValue == 'Select...'
                    % nothing happens
                elseif pValue == 'Select...' && cValue ~= 'Select...'
                    app.HomerDropDown.Items = setdiff( ...
                        app.HomerDropDown.Items, ...
                        char(cValue),'stable');
                elseif pValue ~= 'Select...' && cValue == 'Select...'
                    app.HomerDropDown.Items = ['Select...'; ...
                        sort([app.HomerDropDown.Items(2:end); ...
                        {char(pValue)}])];
                elseif pValue ~= 'Select...' && cValue ~= 'Select...'
                    app.HomerDropDown.Items = setdiff( ...
                        app.HomerDropDown.Items, ...
                        char(cValue),'stable');
                    app.HomerDropDown.Items = ['Select...'; ...
                        sort([app.HomerDropDown.Items(2:end); ...
                        {char(pValue)}])];
                end
            end
            
            % checking dropdown values to enable StartButton
            hValue = string(app.HomerDropDown.Value);
            tValue = cValue;
            if all([hValue,tValue]=='Select...')
                app.StartButton.Enable = 'off';
            else
                app.StartButton.Enable = 'on';
            end
        end

        % Value changed function: AutoStopSwitch
        function AutoStopSwitchValueChanged(app, event)
            switch app.AutoStopSwitch.Value
                case 'On'
                    app.AutoStopEditField.Value = '10';
                    app.AutoStopEditField.Enable = 'on';
                case 'Off'
                    app.AutoStopEditField.Value = 'Never Ending';
                    app.AutoStopEditField.Enable = 'off';
            end
        end

        % Value changed function: SubjectSwitch
        function SubjectSwitchValueChanged(app, event)
            switch app.SubjectSwitch.Value
                case 'On'
                    app.SubjectEditField.Value = 'NS00';
                    app.SubjectEditField.Enable = 'on';
                case 'Off'
                    app.SubjectEditField.Value = 'Not Specified';
                    app.SubjectEditField.Enable = 'off';
            end
        end

        % Value changed function: GoldStandardSwitch
        function GoldStandardSwitchValueChanged(app, event)
            switch app.GoldStandardSwitch.Value
                case 'On'
                    app.GoldStandardEditField.Value = '0';
                    app.GoldStandardEditField.Enable = 'on';
                case 'Off'
                    app.GoldStandardEditField.Value = 'Not Specified';
                    app.GoldStandardEditField.Enable = 'off';
            end
        end

        % Value changed function: AutoStopEditField
        function AutoStopEditFieldValueChanged(app, event)
            value = round(str2double(app.AutoStopEditField.Value));
            if value > 0
                app.AutoStopEditField.Value = num2str(value);
            else
                uialert(app.UIFigure, ...
                    'AutoStop value must be greater than 0.', ...
                    'AutoStop Invalid Value', ...
                    'Icon','error')
                app.AutoStopEditField.Value = event.PreviousValue;
            end
        end

        % Value changed function: GoldStandardEditField
        function GoldStandardEditFieldValueChanged(app, event)
            value = round(str2double(app.GoldStandardEditField.Value));
            if value>=0 && value<=180
                app.GoldStandardEditField.Value = num2str(value);
            else
                uialert(app.UIFigure, ...
                    'GoldStandard value must be between 0 and 180.', ...
                    'GoldStandard Invalid Value', ...
                    'Icon','error')
                app.GoldStandardEditField.Value = event.PreviousValue;
            end
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % disabling all
            appProp = properties(app);
            for i = 1:numel(appProp)
                cProp = appProp{i,1};
                if not(isa(app.(cProp),'matlab.ui.control.Label'))
                    try %#okAGROW
                        app.(cProp).Enable = 'off';
                    end
                end
            end
            
            uishadow(app,'Creating Exel Objects...')
            
            ExelNames = setdiff( ...
                {app.HomerDropDown.Value; ...
                app.ThoraxDropDown.Value}, ...
                'Select...','stable');
            
            if strcmp(app.AutoStopSwitch.Value,'Off')
                AutoStop = Inf;
            else
                AutoStop = str2double(app.AutoStopEditField.Value);
            end
            
            app.ExelObj = Exel(ExelNames,'AutoStop',AutoStop);
            
            hPresence = ~strcmp(app.HomerDropDown.Value,'Select...');
            tPresence = ~strcmp(app.ThoraxDropDown.Value,'Select...');
            if hPresence==false && tPresence==false
                % impossible
            elseif hPresence==false && tPresence==true
                hIndex = []; tIndex = 1;
            elseif hPresence==true && tPresence==false
                hIndex = 1; tIndex = [];
            elseif hPresence==true && tPresence==true
                hIndex = 1; tIndex = 2;
            end
            
            set(app.ExelObj(hIndex),'Segment','Homer')
            set(app.ExelObj(tIndex),'Segment','Thorax')
            
            TempStruct.GoldStandard = str2double(app.GoldStandardEditField.Value);
            TempStruct.OtherInfo = app.OtherInfoEditField.Value;
            
            set(app.ExelObj(hIndex),'UserData',TempStruct);
            set(app.ExelObj(tIndex),'UserData',TempStruct);
            
            hFigure.LastFrame = 0;
            hFigure.Axes = app.HomerUIAxes;
            hFigure.Line = animatedline(app.HomerUIAxes, ...
                'Color',[1,0,0],'LineWidth',1);
            
            tFigure.LastFrame = 0;
            tFigure.Axes = app.ThoraxUIAxes;
            tFigure.Line = animatedline(app.ThoraxUIAxes, ...
                'Color',[0,0,1],'LineWidth',1);
            
            set(app.ExelObj(hIndex),'ExelFigure',hFigure)
            set(app.ExelObj(tIndex),'ExelFigure',tFigure)
            
            set(app.ExelObj(hIndex),'SamplingFcn',@app.SamplingFcn)
            set(app.ExelObj(tIndex),'SamplingFcn',@app.SamplingFcn)
            
            uishadow(app,'Connecting...')
            
            connect(app.ExelObj)
            
            uishadow(app)
            app.StopButton.Enable = 'on';
            start(app.ExelObj)
            
            waitfor(app.ExelObj(end,1),'ConnectionStatus','closed')
            
            appProp = properties(app);
            for i = 1:numel(appProp)
                cProp = appProp{i,1};
                if isa(app.(cProp),'matlab.ui.control.EditField')
                    cDrop = strrep(cProp,'EditField','');
                    if strcmp(app.([cDrop,'Switch']).Value,'On')
                        app.(cProp).Enable = 'on';
                    end
                else
                    try %#okAGROW
                        app.(cProp).Enable = 'on';
                    end
                end
            end
            app.StopButton.Enable = 'off';
            uishadow(app)
        end

        % Value changed function: OtherInfoSwitch
        function OtherInfoSwitchValueChanged(app, event)
            switch app.OtherInfoSwitch.Value
                case 'On'
                    app.OtherInfoEditField.Value = 'N';
                    app.OtherInfoEditField.Enable = 'on';
                case 'Off'
                    app.OtherInfoEditField.Value = 'Not Specified';
                    app.OtherInfoEditField.Enable = 'off';
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            uishadow(app,'Disconnecting...')
            disconnect(app.ExelObj)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'MainAcquisition';
            app.Panel1 = uipanel(app.UIFigure);
            app.Panel1.Title = 'EXEL NAMES & SEGMENTS';
            app.HomerDropDownLabel = uilabel(app.Panel1);
            app.HomerDropDownLabel.Text = 'Homer';
            app.HomerDropDown = uidropdown(app.Panel1);
            app.HomerDropDown.Items = {'Select...'};
            app.HomerDropDown.ValueChangedFcn = createCallbackFcn(app, @HomerDropDownValueChanged, true);
            app.HomerDropDown.Enable = 'off';
            app.HomerDropDown.Value = 'Select...';
            app.ThoraxDropDownLabel = uilabel(app.Panel1);
            app.ThoraxDropDownLabel.Text = 'Thorax';
            app.ThoraxDropDown = uidropdown(app.Panel1);
            app.ThoraxDropDown.Items = {'Select...'};
            app.ThoraxDropDown.ValueChangedFcn = createCallbackFcn(app, @ThoraxDropDownValueChanged, true);
            app.ThoraxDropDown.Enable = 'off';
            app.ThoraxDropDown.Value = 'Select...';
            app.HomerSwitch = uiswitch(app.Panel1, 'slider');
            app.HomerSwitch.ValueChangedFcn = createCallbackFcn(app, @HomerSwitchValueChanged, true);
            app.HomerSwitch.Enable = 'off';
            app.ThoraxSwitch = uiswitch(app.Panel1, 'slider');
            app.ThoraxSwitch.ValueChangedFcn = createCallbackFcn(app, @ThoraxSwitchValueChanged, true);
            app.ThoraxSwitch.Enable = 'off';
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'OPTIONS';
            app.AutoStopSwitch = uiswitch(app.Panel2, 'slider');
            app.AutoStopSwitch.ValueChangedFcn = createCallbackFcn(app, @AutoStopSwitchValueChanged, true);
            app.AutoStopSwitch.Enable = 'off';
            app.SubjectSwitch = uiswitch(app.Panel2, 'slider');
            app.SubjectSwitch.ValueChangedFcn = createCallbackFcn(app, @SubjectSwitchValueChanged, true);
            app.SubjectSwitch.Enable = 'off';
            app.GoldStandardSwitch = uiswitch(app.Panel2, 'slider');
            app.GoldStandardSwitch.ValueChangedFcn = createCallbackFcn(app, @GoldStandardSwitchValueChanged, true);
            app.GoldStandardSwitch.Enable = 'off';
            app.AutoStopEditFieldLabel = uilabel(app.Panel2);
            app.AutoStopEditFieldLabel.Text = 'AutoStop';
            app.AutoStopEditField = uieditfield(app.Panel2, 'text');
            app.AutoStopEditField.ValueChangedFcn = createCallbackFcn(app, @AutoStopEditFieldValueChanged, true);
            app.AutoStopEditField.Enable = 'off';
            app.AutoStopEditField.Value = 'Never Ending';
            app.SubjectEditFieldLabel = uilabel(app.Panel2);
            app.SubjectEditFieldLabel.Text = 'Subject';
            app.SubjectEditField = uieditfield(app.Panel2, 'text');
            app.SubjectEditField.Enable = 'off';
            app.SubjectEditField.Value = 'Not Specified';
            app.GoldStandardEditFieldLabel = uilabel(app.Panel2);
            app.GoldStandardEditFieldLabel.Text = 'GoldStandard';
            app.GoldStandardEditField = uieditfield(app.Panel2, 'text');
            app.GoldStandardEditField.ValueChangedFcn = createCallbackFcn(app, @GoldStandardEditFieldValueChanged, true);
            app.GoldStandardEditField.Enable = 'off';
            app.GoldStandardEditField.Value = 'Not Specified';
            app.OtherInfoEditFieldLabel = uilabel(app.Panel2);
            app.OtherInfoEditFieldLabel.Text = 'OtherInfo';
            app.OtherInfoEditField = uieditfield(app.Panel2, 'text');
            app.OtherInfoEditField.Enable = 'off';
            app.OtherInfoEditField.Value = 'Not Specified';
            app.OtherInfoSwitch = uiswitch(app.Panel2, 'slider');
            app.OtherInfoSwitch.ValueChangedFcn = createCallbackFcn(app, @OtherInfoSwitchValueChanged, true);
            app.OtherInfoSwitch.Enable = 'off';
            app.ScanButton = uibutton(app.UIFigure, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Icon = 'bluetooth_24.png';
            app.ScanButton.Enable = 'off';
            app.ScanButton.Text = 'Scan';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Icon = 'run_24.png';
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Icon = 'stop_24.png';
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.Icon = 'save_24.png';
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.HomerUIAxes = uiaxes(app.UIFigure);
            title(app.HomerUIAxes, 'Homer')
            xlabel(app.HomerUIAxes, 'time (s)')
            ylabel(app.HomerUIAxes, 'degrees (\circ)')
            app.HomerUIAxes.XLim = [0 10];
            app.HomerUIAxes.YLim = [0 180];
            app.ThoraxUIAxes = uiaxes(app.UIFigure);
            title(app.ThoraxUIAxes, 'Thorax')
            xlabel(app.ThoraxUIAxes, 'time (s)')
            ylabel(app.ThoraxUIAxes, 'degrees (\circ)')
            app.ThoraxUIAxes.XLim = [0 10];
            app.ThoraxUIAxes.YLim = [0 180];
        end
    end

    methods (Access = public)

        % Construct app
        function app = MainAcquisition

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end